classdef ScopeTrainerApp_FA26 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        time_label                  matlab.ui.control.Label
        Gauge                       matlab.ui.control.SemicircularGauge
        StartButton                 matlab.ui.control.Button
        OffsetLamp                  matlab.ui.control.Lamp
        AmpLamp                     matlab.ui.control.Lamp
        Lamp_3Label                 matlab.ui.control.Label
        FreqLamp                    matlab.ui.control.Lamp
        Label_2                     matlab.ui.control.Label
        OffsetVoltsEditField        matlab.ui.control.NumericEditField
        OffsetVoltsEditFieldLabel   matlab.ui.control.Label
        AmplitudeVPPEditField       matlab.ui.control.NumericEditField
        AmplitudeVPPEditFieldLabel  matlab.ui.control.Label
        FrequencyHzEditField        matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel   matlab.ui.control.Label
        ConnectLamp                 matlab.ui.control.Lamp
        Label                       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function Start(app, event)
           
            l = visadevlist; 
            for i = 1:height(l) % selecting function generator
                if l{i,4} == "33509B"
                    res = i;
                end
            end
            v = visadev(l{res,1}); % connecting to generator
            j=0;
            while j < 5
                writeline(v, "*IDN?")
                if contains(readline(v), "33509") % check if generator is ready
                    break
                end
                j=j+1; % try 5 times
            end
            if j == 5
                error("Cannot connect to waveform generator");
            end
            app.ConnectLamp.Color = [0 1 0];
            disp('Connected!');
            writeline(v,"OUTP:LOAD INF"); % setting High-Z mode
            flush(v);
            writeline(v,"DISP OFF"); % turning off front display
            connectbit = 1;
            flush(v);
            pause(2);

            waveforms = ["SIN";"SQU";"TRI";"RAMP";"NRAMP"];
            greenbit = 0;
            freqbit = 0;
            ampbit = 0;
            offbit = 0;
            app.FrequencyHzEditField.Value = 0;
            app.AmplitudeVPPEditField.Value = 0;
            app.OffsetVoltsEditField.Value = 0;
            app.Gauge.Value = 0;
            warning('off','instrument:qcinstrument:realValueIsDifferent');
            if connectbit == 1
                tic;
                for i = 0:4
                    rng("shuffle"); % make random number sequence truly random
                    greenbit = 0;
                    app.FrequencyHzEditField.Value = 0;
                    app.AmplitudeVPPEditField.Value = 0;
                    app.OffsetVoltsEditField.Value = 0;
                    fre =  4 + rand(1)*5*10^(randi(3,1));%random frequency between 4 & 5,030 Hz
                    amp = 0.25+(rand(1))*4.75; %random amplitude between 0.25 and 5
                    off = sign((rand(1)-0.5))*(rand(1)+0.1)*2; %random offset between -2.5 & 2.5 volts
                    wave = waveforms(randi(3,1)); %select waveform
                    if i==4 % sends the final noisy waveform
                        writeline(v,"SOURce1:FUNC NOIS");
                        writeline(v,"SOURce1:VOLTage " + string(amp)); % controls underlying wave amplitude
                        writeline(v,"SOURce1:VOLTage:OFFS " + string(off)); % controls underlying wave offset
                        writeline(v,"SOURce1:SUM:AMPLitude +100.0");
                        writeline(v,"SOURce1:SUM:STATe 1");
                        fre = 100 + rand(1)*10^(randi(3,1)); % frequency between 100 and 1100 Hz
                        writeline(v,"SOURce1:SUM:INTernal:FREQuency "+string(fre));
                    else % sends the 'clean' waveforms
                        writeline(v,"APPLy:" + wave + " " + string(fre) + ", " + string(amp) + ", " + string(off));
                        % writeline(v,"APPLy:" + wave + " " + string(fre) + ", " + string(amp) + ", " + string(off));
                    end
                    disp('Wave ' + string(i+1) + ' generated. Use the oscilloscope to enter the frequency, ampiltude and offset into the app.');
                    while greenbit == 0
                        pause(0.05); % pause to see if values update
                        fre_guess = app.FrequencyHzEditField.Value;
                        amp_guess = app.AmplitudeVPPEditField.Value;
                        off_guess = app.OffsetVoltsEditField.Value;
                        if fre_guess >= 0.9*fre && fre_guess <= 1.1*fre
                            % assignin("base",'freq',app.FrequencyHzEditField.Value);
                            app.FreqLamp.Color = [0 1 0];
                            freqbit = 1;
                        else
                            % assignin("base",'freq',app.FrequencyHzEditField.Value);
                            app.FreqLamp.Color = [1 0 0];
                            freqbit = 0;
                        end
                        if amp_guess >= 0.9*amp && amp_guess <= 1.1*amp
                            % assignin("base",'amp',app.AmplitudeVPPEditField.Value);
                            app.AmpLamp.Color = [0 1 0];
                            ampbit = 1;
                        else
                            % assignin("base",'amp',app.AmplitudeVPPEditField.Value);
                            app.AmpLamp.Color = [1 0 0];
                            ampbit = 0;
                        end
                        if abs(off_guess) >= abs(0.9*off) && abs(off_guess) <= abs(1.1*off)
                            % assignin("base",'off',app.OffsetVoltsEditField.Value);
                            app.OffsetLamp.Color = [0 1 0];
                            offbit = 1;
                        else
                            % assignin("base",'off',app.OffsetVoltsEditField.Value);
                            app.OffsetLamp.Color = [1 0 0];
                            offbit = 0;
                        end
                        if offbit == 1 && freqbit == 1 && ampbit == 1
                            greenbit = 1;
                            % assignin("base",'greenbit',greenbit);
                        else
                            greenbit = 0;
                            % assignin("base",'greenbit',greenbit);
                        end
                        app.time_label.Text = string(round(toc,2)); % updating timer
                    end
                    app.Gauge.Value = i+1;
                    % assignin("base",'gauge',app.Gauge.Value);
                end
                disp('DONE!'); % display finish message
                writeline(v,'OUTP OFF'); % turn off function generator
                flush(v); % flush cache on connection
                clear v;
                app.ConnectLamp.Color = [1 0 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ConnectLamp = uilamp(app.UIFigure);
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditField.Limits = [0 100000];
            app.AmplitudeVPPEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeVPPEditFieldLabel.Text = 'Amplitude (VPP)';
            app.AmplitudeVPPEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeVPPEditField.Limits = [-5 5];
            app.OffsetVoltsEditFieldLabel = uilabel(app.UIFigure);
            app.OffsetVoltsEditFieldLabel.Text = 'Offset (Volts)';
            app.OffsetVoltsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffsetVoltsEditField.Limits = [-5 5];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.FreqLamp = uilamp(app.UIFigure);
            app.Lamp_3Label = uilabel(app.UIFigure);
            app.Lamp_3Label.Text = '';
            app.AmpLamp = uilamp(app.UIFigure);
            app.OffsetLamp = uilamp(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Text = 'Start';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [0 5];
            app.time_label = uilabel(app.UIFigure);
            app.time_label.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScopeTrainerApp_FA26

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end