classdef FilterTrainerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FilterTrainerAppUIFigure        matlab.ui.Figure
        OscopeReminderLabel             matlab.ui.control.Label
        ResistorValueLabel              matlab.ui.control.Label
        NextSignalButton                matlab.ui.control.Button
        ValidFilterLabel                matlab.ui.control.Label
        ValidFilterLamp                 matlab.ui.control.Lamp
        SignalsFilteredGauge            matlab.ui.control.LinearGauge
        SignalsFilteredGaugeLabel       matlab.ui.control.Label
        ResistorLamp                    matlab.ui.control.Lamp
        FilterLamp                      matlab.ui.control.Lamp
        ResistorValueEditField          matlab.ui.control.EditField
        CapacitorValuenFEditField       matlab.ui.control.NumericEditField
        CapacitorValuenFEditFieldLabel  matlab.ui.control.Label
        FilterTypeDropDown              matlab.ui.control.DropDown
        FilterTypeDropDownLabel         matlab.ui.control.Label
        SignaltoKeepFrequencyHzLabel    matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        FreqLamp                        matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.EditField
        ConnectLamp                     matlab.ui.control.Lamp
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        max_attenuation = 0.9
        fre_guess = 0;% App-Global variables to store user input and compare
        r_guess = 0;
        c_guess = 100 * 10^(-9); % using a fixed 100 nF capacitor for now
        filt_guess = "";
        wait_for_button = true;
    end
    
    % Calculate ideal filter parameters
    methods (Access = private)
        
        function [cutoff_freq, ideal_res] = CalculateIdealFilter(app, freq, type)
            if type == "Low Pass"
                ideal_res = 1/(2*pi*freq*app.c_guess) * sqrt(1/(app.max_attenuation)^2 - 1);
            end
            if type == "High Pass"
                ideal_res =  1/(2*pi*freq*app.c_guess) * sqrt( (app.max_attenuation^2)/(1-app.max_attenuation^2));
            end
            cutoff_freq = 1/(2*pi*ideal_res*app.c_guess); % cutoff frequency driven by solving for correct attenuation
        end

        
        function UpdatePlot(app)
            R = app.r_guess;
            C = app.c_guess;
            if app.FilterTypeDropDown.Value == "Low Pass"
                sys = tf(1, [(R*C) 1] );
            elseif app.FilterTypeDropDown.Value == "High Pass"
                sys = tf([(R*C) 0], [(R*C) 1] );
            else
                return
            end
            [mag_raw, ~, w] = bode(sys);
            freq_Hz = w / (2*pi);               % Convert rad/s to Hz
            mag_abs = squeeze(mag_raw);         % Drop the 3D array dimensions to a flat vector
            semilogx(app.UIAxes, freq_Hz, mag_abs);
            hold(app.UIAxes, 'on');
            xline(app.UIAxes, app.fre_guess, '--g');
            xline(app.UIAxes, (1/(2*pi*R*C)), '--r');
            hold(app.UIAxes, 'off');
            grid(app.UIAxes, 'on');
            legend(app.UIAxes, ["Transfer Function" "Freq to Keep" "Filter -3dB Freq"], ...
                'Location','best');
        end

        function ClearDisplay(app)
            app.FrequencyHzEditField.Value   = "0";
            app.ResistorValueEditField.Value ="1";
            app.FilterTypeDropDown.Value     = "";
            app.fre_guess                    = 0;
            app.r_guess                      = 0;
            app.wait_for_button              = true;
            app.ValidFilterLamp.Color        = [1 0 0];
            app.FreqLamp.Color               = [1 0 0];
            app.ResistorLamp.Color           = [1 0 0];
            app.FilterLamp.Color             = [1 0 0];
            app.NextSignalButton.Visible     = false;
            app.NextSignalButton.Enable      = false;
            app.OscopeReminderLabel.Visible  = false;
            app.OscopeReminderLabel.Enable   = false;
            cla(app.UIAxes);
            legend(app.UIAxes, 'off');
            focus(app.FrequencyHzEditField);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function Start(app, event)
            % try to connect to waveform gen, otherwise whole thing is
            % pointless
            l = visadevlist; 
            for i = 1:height(l) % selecting function generator
                if l{i,4} == "33509B"
                    res = i;
                end
            end
            v = visadev(l{res,1}); % connecting to generator
            j=0;
            while j < 5
                writeline(v, "*IDN?")
                if contains(readline(v), "33509") % check if generator is ready
                    break
                end
                j=j+1; % try 5 times
            end
            if j == 5
                error("Cannot connect to waveform generator");
            end
            app.StartButton.Text = "Running";
            app.StartButton.Enable = false;
            app.ConnectLamp.Color = [0 1 0];
            pause(0.25);
            disp('Connected!');
            writeline(v,"OUTP:LOAD INF"); % setting High-Z mode
            flush(v);
            writeline(v,"DISP OFF"); % turning off front display
            connectbit = 1;
            flush(v);
            pause(1);
            ylabel(app.UIAxes, 'Attenuation / $\frac{V_{out}}{V_{in}}$', 'Interpreter','latex');

            waveforms = ["SIN";"SQU";"TRI";"RAMP";"NRAMP"];
            app.SignalsFilteredGauge.Value = 0;

            warning('off','instrument:qcinstrument:realValueIsDifferent');
            if connectbit == 1
                tic;
                for i = 1:3
                    % clean up display elements
                    ClearDisplay(app);
                    app.FrequencyHzEditField.Editable   = true;
                    app.FilterTypeDropDown.Editable     = true;
                    app.ResistorValueEditField.Editable = true;
                    app.SignalsFilteredGauge.Value  = i-1;
                    greenbit = 0;
                    amp = 2.5;                           % fixed amplitude for now 
                    wave = waveforms(1);                 % always sine wave for noise exercise
                    off = 0;                             % no offset for now
                    rng("shuffle");                      % make random number sequence truly random
                    
                    if i<3 %  first 2: sends the 'low pass' waveforms
                        filt_type_ideal = "Low Pass";
                        fre =  100 + rand(1)*5*10^(randi(3));% random frequency between 100 & 5,104 Hz
                        writeline(v,"APPLy:" + wave + " " + string(fre) + ", " + string(amp) + ", " + string(off));
                        writeline(v,"SOURce1:SUM:INTernal:FUNC NOIS");
                        writeline(v,"FUNC:NOISe:BANDwidth 200000"); % high frequency noise for initial signals
                        writeline(v, "SOURce1:SUM:AMPLitude +100");
                        writeline(v,"SOURce1:SUM:STATe 1");
                    else % sends the final low frequency noise waveform - needs high pass 
                        filt_type_ideal = "High Pass";
                        % use a different frequency range to keep signal a
                        % little further from the noise band.
                        fre =  1000 + rand(1)*4*10^(randi(3));% random frequency between 1000 & 5000 Hz 
                        writeline(v,"APPLy:" + wave + " " + string(fre) + ", " + string(amp) + ", " + string(off));
                        writeline(v,"SOURce1:SUM:INTernal:FUNC NOIS");
                        writeline(v,"FUNC:NOISe:BANDwidth 60"); % classic 60Hz noise for last signal.
                        writeline(v, "SOURce1:SUM:AMPLitude +100");
                        writeline(v,"SOURce1:SUM:STATe 1");
                    end
                    disp('Wave ' + string(i) + ' generated. Use the oscilloscope to enter the frequency, ampiltude and offset into the app.');
                    [filt_freq_ideal, r_ideal]  = CalculateIdealFilter(app, fre, filt_type_ideal);
                    
                    while greenbit == 0
                        pause(0.05); % pause to see if values update
                        if app.fre_guess >= 0.9*fre && app.fre_guess <= 1.1*fre
                            app.FreqLamp.Color = [0 1 0];
                            freqbit = 1;
                        else
                            app.FreqLamp.Color = [1 0 0];
                            freqbit = 0;
                        end
                        if app.r_guess >= 0.7*r_ideal && app.r_guess <= 1.3*r_ideal
                            app.ResistorLamp.Color = [0 1 0];
                            rbit = 1;
                        else
                            app.ResistorLamp.Color = [1 0 0];
                            rbit = 0;
                        end
                        if app.FilterTypeDropDown.Value == filt_type_ideal
                            app.FilterLamp.Color = [0 1 0];
                            typebit = 1;
                        else
                            app.FilterLamp.Color = [1 0 0];
                            typebit = 0;
                        end
                        if typebit == 1 && freqbit == 1 && rbit == 1
                            greenbit = 1;
                        else
                            greenbit = 0;
                        end
                    end
                    % pause after successful parameters so one can
                    % build/document actual filter
                    app.SignalsFilteredGauge.Value = i;
                    app.ValidFilterLamp.Color       = [0 1 0];
                    app.NextSignalButton.Visible    = true;
                    app.NextSignalButton.Enable     = true;
                    if i==3
                        app.NextSignalButton.Text   = "Finish";
                    end
                    app.OscopeReminderLabel.Visible = true;
                    app.OscopeReminderLabel.Enable  = true;
                    UpdatePlot(app);
                    while(app.wait_for_button)
                        pause(0.1)
                    end
                    
                end
                disp('DONE!'); % display finish message
                ClearDisplay(app);
                app.SignalsFilteredGauge.Value = i;
                writeline(v,'OUTP OFF'); % turn off function generator
                flush(v); % flush cache on connection
                clear v;
                app.ConnectLamp.Color  = [1 0 0];
                app.StartButton.Text   = "Start";
                app.StartButton.Enable = true;
            end
        end

        % Value changing function: FrequencyHzEditField, 
        % ...and 1 other component
        function AnyEditFieldValueChanging(app, event)
            % callback to update values in real time when a value is
            % changed in the field. The challenge is you have to coerce to
            % number because numeric input fields don't have a
            % ValueChanging callback.
            changingValue = event.Value;
            numValue = str2double(changingValue);
            if ~isnan(numValue)
                if (numValue < 1)
                    numValue = 1; % negative or zero values will screw up transfer func
                end
                if event.Source == app.FrequencyHzEditField
                    app.fre_guess = numValue;
                end
                if event.Source == app.ResistorValueEditField
                    app.r_guess = numValue;
                    UpdatePlot(app);
                end
            end
        end

        % Value changed function: FrequencyHzEditField, 
        % ...and 1 other component
        function AnyEditFieldValueChanged(app, event)
            % callback to update values in real time when a value is
            % changed in the field. The challenge is you have to coerce to
            % number because numeric input fields don't have a
            % ValueChanging callback.
            value = event.Value;
            numValue = str2double(value);
            if ~isnan(numValue)
                if (numValue < 1)
                    numValue = 1; % negative or zero values will screw up transfer func
                end
                if event.Source == app.FrequencyHzEditField
                    app.fre_guess = numValue;
                end
                if event.Source == app.ResistorValueEditField
                    app.r_guess = numValue;
                    UpdatePlot(app);
                end
            end
        end

        % Button pushed function: NextSignalButton
        function NextSignalButtonPushed(app, event)
            app.wait_for_button = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FilterTrainerAppUIFigure = uifigure('Visible', 'off');
            app.FilterTrainerAppUIFigure.Name = 'FilterTrainer App';
            app.UIAxes = uiaxes(app.FilterTrainerAppUIFigure);
            title(app.UIAxes, 'Transfer Function for Selected Filter')
            xlabel(app.UIAxes, 'Frequency / Hz')
            ylabel(app.UIAxes, 'Attenuation')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0.5 100000];
            app.UIAxes.YLim = [0 1.1];
            app.UIAxes.XTick = [0.1 10 100 1000 10000 100000];
            app.UIAxes.XScale = 'log';
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ConnectLamp = uilamp(app.FilterTrainerAppUIFigure);
            app.FrequencyHzEditField = uieditfield(app.FilterTrainerAppUIFigure, 'text');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @AnyEditFieldValueChanged, true);
            app.FrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @AnyEditFieldValueChanging, true);
            app.FrequencyHzEditField.Editable = 'off';
            app.FrequencyHzEditField.Tooltip = {'Input the frequency that you want to preserve at the output.'};
            app.FrequencyHzEditField.Placeholder = '0';
            app.Label_2 = uilabel(app.FilterTrainerAppUIFigure);
            app.Label_2.Text = '';
            app.FreqLamp = uilamp(app.FilterTrainerAppUIFigure);
            app.StartButton = uibutton(app.FilterTrainerAppUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Text = 'Start';
            app.SignaltoKeepFrequencyHzLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.SignaltoKeepFrequencyHzLabel.Text = 'Signal Frequency (Hz)';
            app.FilterTypeDropDownLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.FilterTrainerAppUIFigure);
            app.FilterTypeDropDown.Items = {'', 'Low Pass', 'High Pass'};
            app.FilterTypeDropDown.Value = '';
            app.CapacitorValuenFEditFieldLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.CapacitorValuenFEditFieldLabel.Enable = 'off';
            app.CapacitorValuenFEditFieldLabel.Text = 'Capacitor Value (nF)';
            app.CapacitorValuenFEditField = uieditfield(app.FilterTrainerAppUIFigure, 'numeric');
            app.CapacitorValuenFEditField.Limits = [0 Inf];
            app.CapacitorValuenFEditField.Editable = 'off';
            app.CapacitorValuenFEditField.Enable = 'off';
            app.CapacitorValuenFEditField.Tooltip = {'You cannot change the capacitor value. Use the capacitor you were provided for this activity.'};
            app.CapacitorValuenFEditField.Value = 10;
            app.ResistorValueEditField = uieditfield(app.FilterTrainerAppUIFigure, 'text');
            app.ResistorValueEditField.ValueChangedFcn = createCallbackFcn(app, @AnyEditFieldValueChanged, true);
            app.ResistorValueEditField.ValueChangingFcn = createCallbackFcn(app, @AnyEditFieldValueChanging, true);
            app.ResistorValueEditField.Editable = 'off';
            app.ResistorValueEditField.Tooltip = {'Use your resistor substitution box to implement your chosen resistor value.'};
            app.FilterLamp = uilamp(app.FilterTrainerAppUIFigure);
            app.ResistorLamp = uilamp(app.FilterTrainerAppUIFigure);
            app.SignalsFilteredGaugeLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.SignalsFilteredGaugeLabel.Text = 'Signals Filtered';
            app.SignalsFilteredGauge = uigauge(app.FilterTrainerAppUIFigure, 'linear');
            app.SignalsFilteredGauge.Limits = [0 3];
            app.SignalsFilteredGauge.MajorTicks = [0 1 2 3];
            app.SignalsFilteredGauge.MinorTicksMode = 'manual';
            app.ValidFilterLamp = uilamp(app.FilterTrainerAppUIFigure);
            app.ValidFilterLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.ValidFilterLabel.Text = 'Valid Filter';
            app.NextSignalButton = uibutton(app.FilterTrainerAppUIFigure, 'push');
            app.NextSignalButton.ButtonPushedFcn = createCallbackFcn(app, @NextSignalButtonPushed, true);
            app.NextSignalButton.Enable = 'off';
            app.NextSignalButton.Visible = 'off';
            app.NextSignalButton.Tooltip = {'Next signal is available once the current signal has been filtered.'};
            app.NextSignalButton.Text = 'Next Signal';
            app.ResistorValueLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.ResistorValueLabel.Text = 'Resistor Value (Ω)';
            app.OscopeReminderLabel = uilabel(app.FilterTrainerAppUIFigure);
            app.OscopeReminderLabel.Enable = 'off';
            app.OscopeReminderLabel.Visible = 'off';
            app.OscopeReminderLabel.Text = 'Remember to take a picture of your oscilloscope screen before advancing to the next signal';
            % Show the figure after all components are created
            app.FilterTrainerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilterTrainerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FilterTrainerAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FilterTrainerAppUIFigure)
        end
    end
end